classdef GrinCalcApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        HomeMenu                       matlab.ui.container.Menu
        SaveProjectMenu                matlab.ui.container.Menu
        SaveProjectAsMenu              matlab.ui.container.Menu
        LoadProjectMenu                matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
        Console                        matlab.ui.control.TextArea
        TabGroup                       matlab.ui.container.TabGroup
        StackTab                       matlab.ui.container.Tab
        n0                             matlab.ui.control.NumericEditField
        n0EditFieldLabel               matlab.ui.control.Label
        Wavelengt                      matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel     matlab.ui.control.Label
        SamplingLinesCheckBox          matlab.ui.control.CheckBox
        SupportLinesCheckBox           matlab.ui.control.CheckBox
        showCropRec                    matlab.ui.control.CheckBox
        ShowCropedIm                   matlab.ui.control.CheckBox
        LayerSpinner                   matlab.ui.control.Spinner
        LayerSpinnerLabel              matlab.ui.control.Label
        CropStackButton                matlab.ui.control.Button
        DeleteLineButton               matlab.ui.control.Button
        CreateSamplingLineButton       matlab.ui.control.Button
        CreateSupportLineButton        matlab.ui.control.Button
        ImportImagesButton             matlab.ui.control.Button
        LineTree                       matlab.ui.container.Tree
        SamplingLinesNode              matlab.ui.container.TreeNode
        SupportLinesNode               matlab.ui.container.TreeNode
        UIAxes                         matlab.ui.control.UIAxes
        LineTab                        matlab.ui.container.Tab
        NormalizeDataButton            matlab.ui.control.Button
        EdgeMinLayerEditField          matlab.ui.control.NumericEditField
        EdgeMinLayerEditFieldLabel     matlab.ui.control.Label
        CenterDistEditField            matlab.ui.control.NumericEditField
        CenterDistEditFieldLabel       matlab.ui.control.Label
        EdgeDistEditField              matlab.ui.control.NumericEditField
        EdgeDistEditFieldLabel         matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        FindEdgeMinButton              matlab.ui.control.Button
        FindPeaksButton                matlab.ui.control.Button
        FindCenterButton               matlab.ui.control.Button
        FindEdgeButton                 matlab.ui.control.Button
        FlipDataButton                 matlab.ui.control.Button
        DataInterpolationButtonGroup   matlab.ui.container.ButtonGroup
        SavGoButton                    matlab.ui.control.RadioButton
        GaussianButton                 matlab.ui.control.RadioButton
        RawButton                      matlab.ui.control.RadioButton
        ShowPeaksCheckBox              matlab.ui.control.CheckBox
        ShowalllayersCheckBox          matlab.ui.control.CheckBox
        NormalizedDataCheckBox         matlab.ui.control.CheckBox
        SamplingLineSpinner            matlab.ui.control.Spinner
        SamplingLineLabel              matlab.ui.control.Label
        LayerSpinnerLine               matlab.ui.control.Spinner
        LayerLabel                     matlab.ui.control.Label
        UIAxesLine                     matlab.ui.control.UIAxes
        PhasePlotTab                   matlab.ui.container.Tab
        EstimateGradientButton         matlab.ui.control.Button
        PhaseGradientCheckBox          matlab.ui.control.CheckBox
        SampledPointsCheckBox          matlab.ui.control.CheckBox
        SelectRemoveButton             matlab.ui.control.Button
        GetPhasePointsButton           matlab.ui.control.Button
        SamplingLineSpinner_2          matlab.ui.control.Spinner
        SamplingLineSpinner_2Label     matlab.ui.control.Label
        UIAxesPhase                    matlab.ui.control.UIAxes
        ngradientTab                   matlab.ui.container.Tab
        EstimaterefractiveindexButton  matlab.ui.control.Button
        SamplingLineSpinner_3          matlab.ui.control.Spinner
        SamplingLineSpinner_3Label     matlab.ui.control.Label
        UIAxesN                        matlab.ui.control.UIAxes
        nStackViewTab                  matlab.ui.container.Tab
        CalibrationImageTab            matlab.ui.container.Tab
        PixelSize                      matlab.ui.control.NumericEditField
        PixelSizeEditFieldLabel        matlab.ui.control.Label
        CalibDist                      matlab.ui.control.NumericEditField
        DistanceumEditFieldLabel       matlab.ui.control.Label
        CalibrateImageButton           matlab.ui.control.Button
        ImportCalibrationImageButton   matlab.ui.control.Button
        UIAxesCalibration              matlab.ui.control.UIAxes
    end

    properties (Access = public)
        lens = nan;
        currentLineId = nan;
        lineType = nan;
        consoleText = nan;
        saveLabel = nan;
        awn = nan;
        listenForKey = false;
        keyIn = '';
        calibrationLine = nan;
        projectName = 'Untiteled Project';
    end

    methods (Access = public)
        checkImportedData(app)
        uppdateLinePlot(app)

        function uppdateImstackImage(app)
            if app.ShowCropedIm.Value
                imshow(app.lens.imStack(:,:,app.LayerSpinner.Value),'Parent',app.UIAxes)
                if app.SupportLinesCheckBox.Value
                    app.lens.AppPlotLines(app.UIAxes,'support')
                    if ~isnan(app.currentLineId) && isequal(app.lineType,'support')
                        app.lens.AppPlotLines(app.UIAxes,'support',app.currentLineId)
                    end
                end
                if app.SamplingLinesCheckBox.Value
                    app.lens.AppPlotLines(app.UIAxes,'sampling')
                    if ~isnan(app.currentLineId) && isequal(app.lineType,'sampling')
                        app.lens.AppPlotLines(app.UIAxes,'sampling',app.currentLineId)
                    end
                end
            else
                imshow(app.lens.imStackRaw(:,:,app.LayerSpinner.Value),'Parent',app.UIAxes)
                if app.showCropRec.Value && ~isempty(app.lens.cropRect)
                    drawrectangle(app.UIAxes,'Position',app.lens.cropRect,'Color',[0.4660 0.6740 0.1880],'InteractionsAllowed',"none");
                end
            end
        end

        function addTwoConsole(app,tx)
            txLines = length(app.consoleText);
            app.consoleText{txLines + 1} = tx;
            app.ConsoleValueChanged
        end

        %         function checkImportedData(app)
        %             if ~isequal(class(app.lens),'Imstack')
        %                 app.addTwoConsole('Error: The imported data is not an GrinCalc project')
        %                 app.lens = nan;
        %                 return
        %             end
        %             if ~isempty(app.lens.imStack)
        %                 app.LayerSpinner.Limits = [1,app.lens.imNums];
        %                 app.LayerSpinner.Enable = true;
        %                 app.CropStackButton.Enable = true;
        %             end
        %             if ~isempty(app.lens.imStack)
        %                 app.ShowCropedIm.Enable = true;
        %                 app.showCropRec.Enable = true;
        %                 app.showCropRec.Value = true;
        %                 app.CreateSupportLineButton.Enable = true;
        %                 app.CreateSamplingLineButton.Enable = true;
        %             end
        %             if ~isempty(app.lens.mLines)
        %                 app.SamplingLinesCheckBox.Value = true;
        %                 app.SamplingLinesCheckBox.Enable = true;
        %                 app.DeleteLineButton.Enable = true;
        %             end
        %             if ~isempty(app.lens.supLines)
        %                 app.SupportLinesCheckBox.Value = true;
        %                 app.SupportLinesCheckBox.Enable = true;
        %                 app.DeleteLineButton.Enable = true;
        %             end
        %         end

        %         function drawSelected(app)
        %
        %         end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = app.projectName;
            app.lens = Imstack;
            app.consoleText = {'Startup'};
            app.ConsoleValueChanged

            infile = importdata('C:\Users\Mikael\Dev\Refractive_index_approximation\saves\test1.mat');
            app.lens = infile;
            app.checkImportedData()
            app.uppdateImstackImage()
            app.uppdateLinePlot()
            app.UIFigure.Name = 'test1';
        end

        % Button pushed function: ImportImagesButton
        function ImportImagesButtonPushed(app, event)
            app.lens.importStack
            app.lens.dispRawImage(1,app.UIAxes)
            app.LayerSpinner.Limits = [1,app.lens.imNums];
            app.LayerSpinnerLine.Limits = [1,app.lens.imNums];
            app.LayerSpinner.Enable = true;
            app.CropStackButton.Enable = true;
        end

        % Value changed function: ShowCropedIm
        function ShowCropedImValueChanged(app, event)
            app.uppdateImstackImage()
        end

        % Button pushed function: CropStackButton
        function CropStackButtonPushed(app, event)
            app.addTwoConsole('Draw a cropping rectangle. Press "enter" when done or "Q" to cansel')
            app.listenForKey = true;
            roi = drawrectangle(app.UIAxes);
            uiwait(app.UIFigure)
            if isequal(app.keyIn,'return')
                app.lens.AppCropStack(roi)
                app.ShowCropedIm.Enable = true;
                app.ShowCropedIm.Value = true;
                app.showCropRec.Enable = true;
                app.ShowCropedImValueChanged
                app.CreateSupportLineButton.Enable = true;
                app.CreateSamplingLineButton.Enable = true;
            else
                delete(roi)
            end
            app.keyIn = '';
        end

        % Value changed function: Console
        function ConsoleValueChanged(app, event)
            app.Console.Value = app.consoleText;
            scroll(app.Console,'bottom')
        end

        % Value changed function: showCropRec
        function showCropRecValueChanged(app, event)
            app.uppdateImstackImage()
        end

        % Menu selected function: SaveProjectMenu
        function SaveProjectMenuSelected(app, event)
            if isnan(app.saveLabel)
                app.SaveProjectAsMenuSelected
            else
                lensVar = app.lens;
                save(app.saveLabel,'lensVar')
            end
        end

        % Menu selected function: LoadProjectMenu
        function LoadProjectMenuSelected(app, event)
            [filename, path] = uigetfile('*.mat','Load save file','saves\');
            infile = importdata(strcat(path,filename));
            app.lens = infile.lensVar;
            app.checkImportedData()
            app.UIFigure.Name = filename(1:end-4);
            app.projectName = filename(1:end-4);
        end

        % Value changed function: LayerSpinner
        function LayerSpinnerValueChanged(app, event)
            app.uppdateImstackImage()
            app.LayerSpinnerLine.Value = app.LayerSpinner.Value;
        end

        % Menu selected function: SaveProjectAsMenu
        function SaveProjectAsMenuSelected(app, event)
            [file,path] = uiputfile('saves\*.mat');
            lensVar = app.lens;
            save(strcat(path,file),'lensVar')
            app.saveLabel = strcat(path,file);
            app.UIFigure.Name = file(1:end-4);
            app.projectName = file(1:end-4);
        end

        % Button pushed function: CreateSupportLineButton
        function CreateSupportLineButtonPushed(app, event)
            app.addTwoConsole('Draw a support line. Press "enter" when done or "Q" to cansel')
            roi = drawline(app.UIAxes);
            app.listenForKey = true;
            uiwait(app.UIFigure)
            if isequal(app.keyIn,'return')
                ind = app.lens.AppCreateSupportLine(roi);
                lineName = strcat('Support Line','',num2str(ind));
                uitreenode(app.SupportLinesNode,'Text',lineName,"NodeData",ind);
                expand(app.LineTree)
                app.SupportLinesCheckBox.Value = true;
                app.SupportLinesCheckBox.Enable = true;
                app.SupportLinesCheckBoxValueChanged
                app.DeleteLineButton.Enable = true;
                app.currentLineId = ind;
                app.lineType = 'support';
            else
                delete(roi)
            end
            app.uppdateImstackImage()
            app.keyIn = '';
        end

        % Callback function
        function OkButtonPushed(app, event)
            app.awn = true;
            uiresume(app.UIFigure)
            app.OkButton.Visible = false;
            app.CanselButton.Visible = false;
        end

        % Callback function
        function CanselButtonPushed(app, event)
            app.awn = false;
            uiresume(app.UIFigure)
            app.OkButton.Visible = false;
            app.CanselButton.Visible = false;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if app.listenForKey
                key = event.Key;
                if ismember(key,{'q','return'})
                    app.listenForKey = false;
                    app.keyIn = key;
                    uiresume(app.UIFigure)
                end
            end
        end

        % Callback function
        function TestButtonPushed(app, event)

        end

        % Callback function
        function testButtonValueChanged(app, event)

        end

        % Value changed function: SupportLinesCheckBox
        function SupportLinesCheckBoxValueChanged(app, event)
            app.uppdateImstackImage()
        end

        % Button pushed function: CreateSamplingLineButton
        function CreateSamplingLineButtonPushed(app, event)
            app.addTwoConsole('Draw a sampling line. Press "enter" when done or "Q" to cansel')
            roi = drawline(app.UIAxes);
            app.listenForKey = true;
            uiwait(app.UIFigure)
            if isequal(app.keyIn,'return')
                ind = app.lens.AppCreateSamplingLine(roi);
                lineName = strcat('Sampling Line','',num2str(ind));
                uitreenode(app.SamplingLinesNode,'Text',lineName,"NodeData",ind);
                expand(app.LineTree)
                app.SamplingLinesCheckBox.Value = true;
                app.SamplingLinesCheckBox.Enable = true;
                app.SamplingLinesCheckBoxValueChanged
                app.currentLineId = ind;
                app.SamplingLineSpinner.Value = ind;
                if nnz(~cellfun(@isempty,app.lens.mLines)) > 1
                    app.SamplingLineSpinner.Enable = true;
                end
                app.lineType = 'sampling';
                app.DeleteLineButton.Enable = true;
            else
                delete(roi)
            end
            app.uppdateImstackImage()
            app.keyIn = '';
        end

        % Value changed function: SamplingLinesCheckBox
        function SamplingLinesCheckBoxValueChanged(app, event)
            app.uppdateImstackImage()
        end

        % Selection changed function: LineTree
        function LineTreeSelectionChanged(app, event)
            selectedNodes = app.LineTree.SelectedNodes;
            if isequal(selectedNodes.Text(1:2),'Sa')
                app.currentLineId = selectedNodes.NodeData;
                app.lineType = 'sampling';
            elseif isequal(selectedNodes.Text(1:2),'Su')
                app.currentLineId = selectedNodes.NodeData;
                app.lineType = 'support';
            end
            app.uppdateImstackImage()
        end

        % Button pushed function: DeleteLineButton
        function DeleteLineButtonPushed(app, event)
            app.lens.AppDeleteLine(app.lineType,app.currentLineId)
            app.lineType = nan;
            app.currentLineId = nan;
            if nnz(~cellfun(@isempty,app.lens.mLines)) < 2
                app.SamplingLineSpinner.Enable = false;
            end
            node = app.LineTree.SelectedNodes;
            node.delete;            
            app.SamplingLineSpinner.Value = find(~cellfun(@isempty,app.lens.mLines),1);
            app.uppdateImstackImage()
        end

        % Value changed function: Wavelengt
        function WavelengtValueChanged(app, event)
            app.lambda = app.Wavelengt.Value;
        end

        % Value changed function: n0
        function n0ValueChanged(app, event)
            app.n0 = app.n0.Value;
        end

        % Button pushed function: ImportCalibrationImageButton
        function ImportCalibrationImageButtonPushed(app, event)
            [filename, path] = uigetfile('*');
            filePath = strcat(path,filename);
            I = imread(filePath);
            app.lens.calibIm = I;
            imshow(I,'Parent',app.UIAxesCalibration)
        end

        % Value changed function: CalibDist
        function CalibDistValueChanged(app, event)
            value = app.CalibDist.Value;
            if isnan(app.calibrationLine)
                app.addTwoConsole('Error: You need to import and calibrate an image first')
            else
                lineDist = norm(app.calibrationLine(2,:)-app.calibrationLine(1,:));
                mScale = 10^-6;
                app.lens.pixelSize = (value*mScale)/lineDist;
                app.lens.calibKnownDistance = value*mScale;
                app.PixelSize.Value = app.lens.pixelSize;
            end
        end

        % Button pushed function: CalibrateImageButton
        function CalibrateImageButtonPushed(app, event)
            app.addTwoConsole('Draw a line in the image for a known distance.  Press "enter" when done or "Q" to cansel')
            roiLine = drawline(app.UIAxesCalibration);
            %             roiLine = app.lens.AppCalibrateImage(app.UIAxesCalibration);
            app.listenForKey = true;
            uiwait(app.UIFigure)
            if isequal(app.keyIn,'return')
                app.calibrationLine = roiLine.Position;
                app.lens.calibLine = roiLine.Position;
                %                 app.lens.pixelSize = norm(tempLine(2,:)-tempLine(1,:));
                app.addTwoConsole('Enter known distance to complete calibration')
            else
                delete(roiLine)
            end
            app.keyIn = '';
        end

        % Value changed function: LayerSpinnerLine
        function LayerSpinnerLineValueChanged(app, event)
            app.LayerSpinner.Value = app.LayerSpinnerLine.Value;
            app.uppdateLinePlot
        end

        % Selection changed function: DataInterpolationButtonGroup
        function DataInterpolationButtonGroupSelectionChanged(app, event)
%             clf(app.UIAxesLine,'reset')
            app.uppdateLinePlot()
        end

        % Value changed function: ShowalllayersCheckBox
        function ShowalllayersCheckBoxValueChanged(app, event)
            app.uppdateLinePlot()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.HomeMenu = uimenu(app.UIFigure);
            app.HomeMenu.Text = 'Home';
            app.SaveProjectMenu = uimenu(app.HomeMenu);
            app.SaveProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
            app.SaveProjectMenu.Text = 'Save Project';
            app.SaveProjectAsMenu = uimenu(app.HomeMenu);
            app.SaveProjectAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectAsMenuSelected, true);
            app.SaveProjectAsMenu.Text = 'Save Project As...';
            app.LoadProjectMenu = uimenu(app.HomeMenu);
            app.LoadProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadProjectMenuSelected, true);
            app.LoadProjectMenu.Text = 'Load Project';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StackTab = uitab(app.TabGroup);
            app.StackTab.Title = 'Stack';
            app.UIAxes = uiaxes(app.StackTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LineTree = uitree(app.StackTab);
            app.LineTree.SelectionChangedFcn = createCallbackFcn(app, @LineTreeSelectionChanged, true);
            app.SamplingLinesNode = uitreenode(app.LineTree);
            app.SamplingLinesNode.Text = 'Sampling Lines';
            app.SupportLinesNode = uitreenode(app.LineTree);
            app.SupportLinesNode.Text = 'Support Lines';
            app.ImportImagesButton = uibutton(app.StackTab, 'push');
            app.ImportImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImagesButtonPushed, true);
            app.ImportImagesButton.Text = 'Import Images';
            app.CreateSupportLineButton = uibutton(app.StackTab, 'push');
            app.CreateSupportLineButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSupportLineButtonPushed, true);
            app.CreateSupportLineButton.Enable = 'off';
            app.CreateSupportLineButton.Text = 'Create Support Line';
            app.CreateSamplingLineButton = uibutton(app.StackTab, 'push');
            app.CreateSamplingLineButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSamplingLineButtonPushed, true);
            app.CreateSamplingLineButton.Enable = 'off';
            app.CreateSamplingLineButton.Text = 'Create Sampling Line';
            app.DeleteLineButton = uibutton(app.StackTab, 'push');
            app.DeleteLineButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLineButtonPushed, true);
            app.DeleteLineButton.Enable = 'off';
            app.DeleteLineButton.Text = 'Delete Line';
            app.CropStackButton = uibutton(app.StackTab, 'push');
            app.CropStackButton.ButtonPushedFcn = createCallbackFcn(app, @CropStackButtonPushed, true);
            app.CropStackButton.Enable = 'off';
            app.CropStackButton.Text = 'Crop Stack';
            app.LayerSpinnerLabel = uilabel(app.StackTab);
            app.LayerSpinnerLabel.Text = 'Layer';
            app.LayerSpinner = uispinner(app.StackTab);
            app.LayerSpinner.Limits = [0 1];
            app.LayerSpinner.ValueChangedFcn = createCallbackFcn(app, @LayerSpinnerValueChanged, true);
            app.LayerSpinner.Enable = 'off';
            app.LayerSpinner.Value = 1;
            app.ShowCropedIm = uicheckbox(app.StackTab);
            app.ShowCropedIm.ValueChangedFcn = createCallbackFcn(app, @ShowCropedImValueChanged, true);
            app.ShowCropedIm.Enable = 'off';
            app.ShowCropedIm.Text = 'Cropped image';
            app.showCropRec = uicheckbox(app.StackTab);
            app.showCropRec.ValueChangedFcn = createCallbackFcn(app, @showCropRecValueChanged, true);
            app.showCropRec.Enable = 'off';
            app.showCropRec.Text = 'Show Crop Rect';
            app.SupportLinesCheckBox = uicheckbox(app.StackTab);
            app.SupportLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SupportLinesCheckBoxValueChanged, true);
            app.SupportLinesCheckBox.Enable = 'off';
            app.SupportLinesCheckBox.Text = 'Support Lines';
            app.SamplingLinesCheckBox = uicheckbox(app.StackTab);
            app.SamplingLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SamplingLinesCheckBoxValueChanged, true);
            app.SamplingLinesCheckBox.Enable = 'off';
            app.SamplingLinesCheckBox.Text = 'Sampling Lines';
            app.WavelengthnmEditFieldLabel = uilabel(app.StackTab);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength [nm]';
            app.Wavelengt = uieditfield(app.StackTab, 'numeric');
            app.Wavelengt.ValueChangedFcn = createCallbackFcn(app, @WavelengtValueChanged, true);
            app.n0EditFieldLabel = uilabel(app.StackTab);
            app.n0EditFieldLabel.Text = 'n0';
            app.n0 = uieditfield(app.StackTab, 'numeric');
            app.n0.ValueChangedFcn = createCallbackFcn(app, @n0ValueChanged, true);
            app.LineTab = uitab(app.TabGroup);
            app.LineTab.Title = 'Line';
            app.UIAxesLine = uiaxes(app.LineTab);
            app.UIAxesLine.XTick = [];
            app.UIAxesLine.YTick = [];
            app.LayerLabel = uilabel(app.LineTab);
            app.LayerLabel.Text = 'Layer  ';
            app.LayerSpinnerLine = uispinner(app.LineTab);
            app.LayerSpinnerLine.Limits = [1 Inf];
            app.LayerSpinnerLine.ValueChangedFcn = createCallbackFcn(app, @LayerSpinnerLineValueChanged, true);
            app.LayerSpinnerLine.Value = 1;
            app.SamplingLineLabel = uilabel(app.LineTab);
            app.SamplingLineLabel.Text = 'Sampling Line  ';
            app.SamplingLineSpinner = uispinner(app.LineTab);
            app.SamplingLineSpinner.Limits = [1 Inf];
            app.SamplingLineSpinner.Enable = 'off';
            app.SamplingLineSpinner.Value = 1;
            app.NormalizedDataCheckBox = uicheckbox(app.LineTab);
            app.NormalizedDataCheckBox.Enable = 'off';
            app.NormalizedDataCheckBox.Text = 'Normalized Data';
            app.ShowalllayersCheckBox = uicheckbox(app.LineTab);
            app.ShowalllayersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowalllayersCheckBoxValueChanged, true);
            app.ShowalllayersCheckBox.Text = 'Show all layers';
            app.ShowalllayersCheckBox.Value = true;
            app.ShowPeaksCheckBox = uicheckbox(app.LineTab);
            app.ShowPeaksCheckBox.Enable = 'off';
            app.ShowPeaksCheckBox.Text = 'Show Peaks';
            app.DataInterpolationButtonGroup = uibuttongroup(app.LineTab);
            app.DataInterpolationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataInterpolationButtonGroupSelectionChanged, true);
            app.DataInterpolationButtonGroup.Title = 'Data Interpolation';
            app.RawButton = uiradiobutton(app.DataInterpolationButtonGroup);
            app.RawButton.Text = 'Raw';
            app.RawButton.Value = true;
            app.GaussianButton = uiradiobutton(app.DataInterpolationButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.SavGoButton = uiradiobutton(app.DataInterpolationButtonGroup);
            app.SavGoButton.Text = 'SavGo';
            app.FlipDataButton = uibutton(app.LineTab, 'push');
            app.FlipDataButton.Text = 'Flip Data';
            app.FindEdgeButton = uibutton(app.LineTab, 'push');
            app.FindEdgeButton.Text = 'Find Edge';
            app.FindCenterButton = uibutton(app.LineTab, 'push');
            app.FindCenterButton.Text = 'Find Center';
            app.FindPeaksButton = uibutton(app.LineTab, 'push');
            app.FindPeaksButton.Text = 'Find Peaks';
            app.FindEdgeMinButton = uibutton(app.LineTab, 'push');
            app.FindEdgeMinButton.Text = 'Find Edge Min';
            app.Button = uibutton(app.LineTab, 'push');
            app.EdgeDistEditFieldLabel = uilabel(app.LineTab);
            app.EdgeDistEditFieldLabel.Text = 'Edge Dist';
            app.EdgeDistEditField = uieditfield(app.LineTab, 'numeric');
            app.CenterDistEditFieldLabel = uilabel(app.LineTab);
            app.CenterDistEditFieldLabel.Text = 'Center Dist';
            app.CenterDistEditField = uieditfield(app.LineTab, 'numeric');
            app.EdgeMinLayerEditFieldLabel = uilabel(app.LineTab);
            app.EdgeMinLayerEditFieldLabel.Text = 'Edge Min Layer';
            app.EdgeMinLayerEditField = uieditfield(app.LineTab, 'numeric');
            app.NormalizeDataButton = uibutton(app.LineTab, 'push');
            app.NormalizeDataButton.Text = 'Normalize Data';
            app.PhasePlotTab = uitab(app.TabGroup);
            app.PhasePlotTab.Title = 'Phase Plot';
            app.UIAxesPhase = uiaxes(app.PhasePlotTab);
            app.UIAxesPhase.XTick = [];
            app.UIAxesPhase.YTick = [];
            app.SamplingLineSpinner_2Label = uilabel(app.PhasePlotTab);
            app.SamplingLineSpinner_2Label.Text = 'Sampling Line  ';
            app.SamplingLineSpinner_2 = uispinner(app.PhasePlotTab);
            app.SamplingLineSpinner_2.Limits = [1 Inf];
            app.SamplingLineSpinner_2.Enable = 'off';
            app.SamplingLineSpinner_2.Value = 1;
            app.GetPhasePointsButton = uibutton(app.PhasePlotTab, 'push');
            app.GetPhasePointsButton.Text = 'Get Phase Points';
            app.SelectRemoveButton = uibutton(app.PhasePlotTab, 'push');
            app.SelectRemoveButton.Text = 'Select & Remove';
            app.SampledPointsCheckBox = uicheckbox(app.PhasePlotTab);
            app.SampledPointsCheckBox.Text = 'Sampled Points';
            app.PhaseGradientCheckBox = uicheckbox(app.PhasePlotTab);
            app.PhaseGradientCheckBox.Text = 'Phase Gradient';
            app.EstimateGradientButton = uibutton(app.PhasePlotTab, 'push');
            app.EstimateGradientButton.Text = 'Estimate Gradient';
            app.ngradientTab = uitab(app.TabGroup);
            app.ngradientTab.Title = 'n-gradient';
            app.UIAxesN = uiaxes(app.ngradientTab);
            app.UIAxesN.XTick = [];
            app.UIAxesN.YTick = [];
            app.SamplingLineSpinner_3Label = uilabel(app.ngradientTab);
            app.SamplingLineSpinner_3Label.Text = 'Sampling Line  ';
            app.SamplingLineSpinner_3 = uispinner(app.ngradientTab);
            app.SamplingLineSpinner_3.Limits = [1 Inf];
            app.SamplingLineSpinner_3.Enable = 'off';
            app.SamplingLineSpinner_3.Value = 1;
            app.EstimaterefractiveindexButton = uibutton(app.ngradientTab, 'push');
            app.EstimaterefractiveindexButton.Text = 'Estimate refractive index';
            app.nStackViewTab = uitab(app.TabGroup);
            app.nStackViewTab.Title = 'n-StackView';
            app.CalibrationImageTab = uitab(app.TabGroup);
            app.CalibrationImageTab.Title = 'Calibration Image';
            app.UIAxesCalibration = uiaxes(app.CalibrationImageTab);
            app.UIAxesCalibration.XTick = [];
            app.UIAxesCalibration.YTick = [];
            app.ImportCalibrationImageButton = uibutton(app.CalibrationImageTab, 'push');
            app.ImportCalibrationImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportCalibrationImageButtonPushed, true);
            app.ImportCalibrationImageButton.Text = 'Import Calibration Image';
            app.CalibrateImageButton = uibutton(app.CalibrationImageTab, 'push');
            app.CalibrateImageButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateImageButtonPushed, true);
            app.CalibrateImageButton.Text = 'Calibrate Image';
            app.DistanceumEditFieldLabel = uilabel(app.CalibrationImageTab);
            app.DistanceumEditFieldLabel.Text = 'Distance [um]';
            app.CalibDist = uieditfield(app.CalibrationImageTab, 'numeric');
            app.CalibDist.ValueChangedFcn = createCallbackFcn(app, @CalibDistValueChanged, true);
            app.PixelSizeEditFieldLabel = uilabel(app.CalibrationImageTab);
            app.PixelSizeEditFieldLabel.Text = 'Pixel Size';
            app.PixelSize = uieditfield(app.CalibrationImageTab, 'numeric');
            app.PixelSize.Editable = 'off';
            app.Console = uitextarea(app.UIFigure);
            app.Console.ValueChangedFcn = createCallbackFcn(app, @ConsoleValueChanged, true);
            app.Console.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GrinCalcApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end